classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        EnterTheFiltersMatrixLabel      matlab.ui.control.Label
        Filter1_1                       matlab.ui.control.Spinner
        Convolution2D                   matlab.ui.container.Tab
        SelectImageConv                 matlab.ui.control.EditField
        ChooseFileConv                  matlab.ui.control.Button
        UIAxesConv                      matlab.ui.control.UIAxes
        Filter1_2                       matlab.ui.control.Spinner
        Filter1_3                       matlab.ui.control.Spinner
        Filter2_1                       matlab.ui.control.Spinner
        Filter2_2                       matlab.ui.control.Spinner
        Filter2_3                       matlab.ui.control.Spinner
        Filter3_1                       matlab.ui.control.Spinner
        Filter3_2                       matlab.ui.control.Spinner
        Filter3_3                       matlab.ui.control.Spinner
        SelectImageFileLabel            matlab.ui.control.Label
        ImageConvolutionLabel           matlab.ui.control.Label
        MeanFilteringTab                matlab.ui.container.Tab
        UIAxesMeanFiltering             matlab.ui.control.UIAxes
        ChooseFileMeanFiltering         matlab.ui.control.Button
        SelectimagefileEditFieldLabel   matlab.ui.control.Label
        SelectImageMeanFiltering        matlab.ui.control.EditField
        MeanFilter1_1                   matlab.ui.control.Spinner
        MeanFilter1_2                   matlab.ui.control.Spinner
        MeanFilter1_3                   matlab.ui.control.Spinner
        MeanFilter2_1                   matlab.ui.control.Spinner
        MeanFilter2_2                   matlab.ui.control.Spinner
        MeanFilter2_3                   matlab.ui.control.Spinner
        MeanFilter3_1                   matlab.ui.control.Spinner
        MeanFilter3_2                   matlab.ui.control.Spinner
        MeanFilter3_3                   matlab.ui.control.Spinner
        EnterTheFiltersMatrixLabel_2    matlab.ui.control.Label
        ImageSmoothingusingMeanFilteringLabel  matlab.ui.control.Label
        GaussianFilteringTab            matlab.ui.container.Tab
        SelectimagefileEditField_3Label  matlab.ui.control.Label
        SelectImageGaussian             matlab.ui.control.EditField
        ChooseFileGaussian              matlab.ui.control.Button
        UIAxesGaussian                  matlab.ui.control.UIAxes
        GaussianFilter1_1               matlab.ui.control.Spinner
        GaussianFilter2_1               matlab.ui.control.Spinner
        GaussianFilter3_1               matlab.ui.control.Spinner
        EnterTheFiltersMatrixLabel_3    matlab.ui.control.Label
        GaussianFilter1_2               matlab.ui.control.Spinner
        GaussianFilter2_2               matlab.ui.control.Spinner
        GaussianFilter3_2               matlab.ui.control.Spinner
        GaussianFilter1_3               matlab.ui.control.Spinner
        GaussianFilter2_3               matlab.ui.control.Spinner
        GaussianFilter3_3               matlab.ui.control.Spinner
        ImageSmoothingusingGaussianFilteringLabel  matlab.ui.control.Label
        FrequencySmoothingTab           matlab.ui.container.Tab
        UIAxesFreqSmoothing             matlab.ui.control.UIAxes
        ChooseFilterDropDownLabel       matlab.ui.control.Label
        ChooseFilterDropDown            matlab.ui.control.DropDown
        FilteringwithLowPassandHighPassFiltersLabel  matlab.ui.control.Label
        ChooseFileFreqSmoothing         matlab.ui.control.Button
        SelectFileLabelFreqSmoothing    matlab.ui.control.Label
        SelectImageFreqSmoothing        matlab.ui.control.EditField
        EnterD0valueLabel               matlab.ui.control.Label
        D0                              matlab.ui.control.NumericEditField
        SelectFileLabelFreqSmoothing_2  matlab.ui.control.Label
        HighBoostingTab                 matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        SourceImageButton               matlab.ui.control.Button
        HighBoostButton                 matlab.ui.control.Button
        SourceEditField                 matlab.ui.control.EditField
        SourceImage                     matlab.ui.control.Image
        HighBoostImage                  matlab.ui.control.Image
        HighBoostEditField              matlab.ui.control.NumericEditField
        LowPassImage                    matlab.ui.control.Image
        MedianFilteringTab              matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ClearImageButton                matlab.ui.control.Button
        ClearEditField                  matlab.ui.control.EditField
        ClearImage                      matlab.ui.control.Image
        NoiseImage                      matlab.ui.control.Image
        MedianImage                     matlab.ui.control.Image
        NoiseEditField                  matlab.ui.control.EditField
        SaltPepperEditField             matlab.ui.control.NumericEditField
        NoiseImageButton                matlab.ui.control.Button
        SaltPepperButton                matlab.ui.control.Button
        ResultImageButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mask_input)
            
        end

        % Callback function
        function ChooseFileButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SelectthefilehereEditField_Conv.Value = fullpath;
                im = imread(fullpath);
                
                mask = [0 -1 0;
                    -1 5 -1;
                    0 -1 0];
                mask = mask/sum(sum(mask));
                
                [image_result, matlab_result] = convolution2D(im, mask);
                imshow(image_result,'Parent',app.UIAxes)
            end
        end

        % Callback function
        function ChooseImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.FileEditField_2.Value = fullpath;
                im = imread(fullpath);
                app.EnhancementInImage.ImageSource = im;
                [x, bins] = histog(im);
                bar(app.EnhancementInHist, x, bins);
            end
        end

        % Callback function
        function ApplyButtonPushed(app, event)
            im = imread(app.FileEditField_2.Value);
            im = brighten(im, app.aSlider.Value, app.bSlider.Value);
            [x, bins] = histog(im);
            bar(app.EnhancementOutHist, x, bins);
            app.EnhancementOutImage.ImageSource = im;
        end

        % Callback function
        function ApplyButton_2Pushed(app, event)
            im = imread(app.FileEditField_2.Value);
            im = logtran(im, app.cSlider.Value);
            [x, bins] = histog(im);
            bar(app.EnhancementOutHist, x, bins);
            app.EnhancementOutImage.ImageSource = im;
        end

        % Callback function
        function ApplyButton_3Pushed(app, event)
            im = imread(app.FileEditField_2.Value);
            im = exp(im, app.cSlider.Value);
            [x, bins] = histog(im);
            bar(app.EnhancementOutHist, x, bins);
            app.EnhancementOutImage.ImageSource = im;
        end

        % Callback function
        function bSliderValueChanged(app, event)
            value = app.bSlider.Value;
            app.bEditField.Value = value;
        end

        % Callback function
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.bSlider.Value = value;
        end

        % Callback function
        function aSliderValueChanged(app, event)
            value = app.aSlider.Value;
            app.aSlider.Value = value;
        end

        % Callback function
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.aSlider.Value = value;
        end

        % Callback function
        function ChooseImageButton_2Pushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.FileEditField_3.Value = fullpath;
                im = imread(fullpath);
                app.EqualizationInImage.ImageSource = im;
                [x, bins] = histog(im);
                bar(app.EqualizationInHist, x, bins);

                im_equalized = histog_equalization(im);
                app.EqualizationOutImage.ImageSource = im_equalized;
                [x, bins] = histog(im_equalized);
                bar(app.EqualizationOutHist, x, bins);
            end
        end

        % Button pushed function: SourceImageButton
        function SourceImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SourceEditField.Value = fullpath;
                im = imread(fullpath);
                im = gray_to_rgb(im);
                app.SourceImage.ImageSource = im;
                app.LowPassImage.ImageSource = uint8(low_pass(im));
            end
        end

        % Button pushed function: HighBoostButton
        function HighBoostButtonPushed(app, event)
            im = imread(app.SourceEditField.Value);
            im = gray_to_rgb(im);
            app.HighBoostImage.ImageSource = high_boost(im, app.HighBoostEditField.Value);
        end

        % Button pushed function: ClearImageButton
        function ClearImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.ClearEditField.Value = fullpath;
                im = imread(fullpath);
                app.ClearImage.ImageSource = gray_to_rgb(im);
            end
        end

        % Button pushed function: NoiseImageButton
        function NoiseImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.NoiseEditField.Value = fullpath;
                im = imread(fullpath);
                app.NoiseImage.ImageSource = gray_to_rgb(im);
            end
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            im = app.ClearImage.ImageSource;
            app.NoiseImage.ImageSource = salt_pepper_noise(im, app.SaltPepperEditField.Value);
        end

        % Button pushed function: ResultImageButton
        function ResultImageButtonPushed(app, event)
            im = app.NoiseImage.ImageSource;
            app.MedianImage.ImageSource = median_filter(im);
        end

        % Button pushed function: ChooseFileMeanFiltering
        function ChooseFileButtonPushed_MeanFilter(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SelectImageMeanFiltering.Value = fullpath;
                im = imread(fullpath);
                I = im2double(im);
                
                % Mean filter
                mean_filter = [app.MeanFilter1_1.Value app.MeanFilter1_2.Value app.MeanFilter1_3.Value;
                               app.MeanFilter2_1.Value app.MeanFilter2_2.Value app.MeanFilter2_3.Value;
                               app.MeanFilter3_1.Value app.MeanFilter3_2.Value app.MeanFilter3_3.Value];
                [n, ~] = size(mean_filter);
                mean_filter = mean_filter/n;
                
                [image_result, ~] = convolution2D(im, mean_filter);
                imshow(image_result,'Parent',app.UIAxesMeanFiltering);
            end
        end

        % Button pushed function: ChooseFileFreqSmoothing
        function ChooseFileFreqSmooth(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SelectImageFreqSmoothing.Value = fullpath;
                im = imread(fullpath);
                I = im2double(im);
                
                filter_type = app.ChooseFilterDropDown.Value;
                D0 = app.D0.Value;
                
                image_result = freq_imsmoothing(im, filter_type, D0);
                imshow(image_result,'Parent',app.UIAxesFreqSmoothing);
            end
        end

        % Value changed function: D0
        function D0ValueChanged(app, event)
            value = app.D0.Value;
            
        end

        % Button pushed function: ChooseFileGaussian
        function ChooseFileGaussianButton(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SelectImageGaussian.Value = fullpath;
                im = imread(fullpath);
                I = im2double(im);
                
                % Mean filter
                gaussian_filter =  [app.GaussianFilter1_1.Value app.GaussianFilter1_2.Value app.GaussianFilter1_3.Value;
                                    app.GaussianFilter2_1.Value app.GaussianFilter2_2.Value app.GaussianFilter2_3.Value;
                                    app.GaussianFilter3_1.Value app.GaussianFilter3_2.Value app.GaussianFilter3_3.Value];
                gaussian_filter = gaussian_filter/sum(sum(gaussian_filter));
                
                [image_result, ~] = convolution2D(im, gaussian_filter);
                imshow(image_result,'Parent',app.UIAxesGaussian);
            end
        end

        % Button pushed function: ChooseFileConv
        function ChooseFileConvButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SelectImageConv.Value = fullpath;
                im = imread(fullpath);
                
                mask = [app.Filter1_1.Value app.Filter1_2.Value app.Filter1_3.Value;
                        app.Filter2_1.Value app.Filter2_2.Value app.Filter2_3.Value;
                        app.Filter3_1.Value app.Filter3_2.Value app.Filter3_3.Value];
                mask = mask/sum(sum(mask));
                
                [image_result, matlab_result] = convolution2D(im, mask);
                imshow(image_result,'Parent',app.UIAxesConv)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Convolution2D = uitab(app.TabGroup);
            app.Convolution2D.Title = 'Convolution2D';
            app.SelectImageConv = uieditfield(app.Convolution2D, 'text');
            app.ChooseFileConv = uibutton(app.Convolution2D, 'push');
            app.ChooseFileConv.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileConvButtonPushed, true);
            app.ChooseFileConv.Text = 'Choose File & Apply Convolution';
            app.UIAxesConv = uiaxes(app.Convolution2D);
            title(app.UIAxesConv, 'Convolution result')
            xlabel(app.UIAxesConv, '')
            ylabel(app.UIAxesConv, '')
            app.UIAxesConv.XTick = [];
            app.UIAxesConv.YTick = [];
            app.EnterTheFiltersMatrixLabel = uilabel(app.Convolution2D);
            app.EnterTheFiltersMatrixLabel.Text = 'Enter The Filter''s Matrix';
            app.Filter1_1 = uispinner(app.Convolution2D);
            app.Filter1_2 = uispinner(app.Convolution2D);
            app.Filter1_3 = uispinner(app.Convolution2D);
            app.Filter2_1 = uispinner(app.Convolution2D);
            app.Filter2_2 = uispinner(app.Convolution2D);
            app.Filter2_3 = uispinner(app.Convolution2D);
            app.SelectImageFileLabel = uilabel(app.Convolution2D);
            app.SelectImageFileLabel.Text = 'Select Image File:';
            app.ImageConvolutionLabel = uilabel(app.Convolution2D);
            app.ImageConvolutionLabel.Text = 'Image Convolution';
            app.Filter3_1 = uispinner(app.Convolution2D);
            app.Filter3_2 = uispinner(app.Convolution2D);
            app.Filter3_3 = uispinner(app.Convolution2D);
            app.MeanFilteringTab = uitab(app.TabGroup);
            app.MeanFilteringTab.Title = 'Mean Filtering';
            app.UIAxesMeanFiltering = uiaxes(app.MeanFilteringTab);
            title(app.UIAxesMeanFiltering, 'Image Smoothing using Mean Filter result')
            xlabel(app.UIAxesMeanFiltering, '')
            ylabel(app.UIAxesMeanFiltering, '')
            app.UIAxesMeanFiltering.XTick = [];
            app.UIAxesMeanFiltering.YTick = [];
            app.ChooseFileMeanFiltering = uibutton(app.MeanFilteringTab, 'push');
            app.ChooseFileMeanFiltering.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed_MeanFilter, true);
            app.ChooseFileMeanFiltering.Text = 'Choose File & Apply Mean Filter';
            app.SelectimagefileEditFieldLabel = uilabel(app.MeanFilteringTab);
            app.SelectimagefileEditFieldLabel.Text = 'Select Image File:';
            app.SelectImageMeanFiltering = uieditfield(app.MeanFilteringTab, 'text');
            app.MeanFilter1_1 = uispinner(app.MeanFilteringTab);
            app.MeanFilter1_2 = uispinner(app.MeanFilteringTab);
            app.MeanFilter1_3 = uispinner(app.MeanFilteringTab);
            app.MeanFilter2_1 = uispinner(app.MeanFilteringTab);
            app.MeanFilter2_2 = uispinner(app.MeanFilteringTab);
            app.MeanFilter2_3 = uispinner(app.MeanFilteringTab);
            app.MeanFilter3_1 = uispinner(app.MeanFilteringTab);
            app.MeanFilter3_2 = uispinner(app.MeanFilteringTab);
            app.MeanFilter3_3 = uispinner(app.MeanFilteringTab);
            app.EnterTheFiltersMatrixLabel_2 = uilabel(app.MeanFilteringTab);
            app.EnterTheFiltersMatrixLabel_2.Text = 'Enter The Filter''s Matrix';
            app.ImageSmoothingusingMeanFilteringLabel = uilabel(app.MeanFilteringTab);
            app.ImageSmoothingusingMeanFilteringLabel.Text = 'Image Smoothing using Mean Filtering';
            app.GaussianFilteringTab = uitab(app.TabGroup);
            app.GaussianFilteringTab.Title = 'Gaussian Filtering';
            app.SelectimagefileEditField_3Label = uilabel(app.GaussianFilteringTab);
            app.SelectimagefileEditField_3Label.Text = 'Select image file: ';
            app.SelectImageGaussian = uieditfield(app.GaussianFilteringTab, 'text');
            app.ChooseFileGaussian = uibutton(app.GaussianFilteringTab, 'push');
            app.ChooseFileGaussian.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileGaussianButton, true);
            app.ChooseFileGaussian.Text = 'Choose Image & Apply Gaussian Filter';
            app.UIAxesGaussian = uiaxes(app.GaussianFilteringTab);
            title(app.UIAxesGaussian, 'Image Smoothing using Gaussian Filter result')
            xlabel(app.UIAxesGaussian, '')
            ylabel(app.UIAxesGaussian, '')
            app.UIAxesGaussian.XTick = [];
            app.UIAxesGaussian.YTick = [];
            app.GaussianFilter1_1 = uispinner(app.GaussianFilteringTab);
            app.GaussianFilter2_1 = uispinner(app.GaussianFilteringTab);
            app.GaussianFilter3_1 = uispinner(app.GaussianFilteringTab);
            app.EnterTheFiltersMatrixLabel_3 = uilabel(app.GaussianFilteringTab);
            app.EnterTheFiltersMatrixLabel_3.Text = 'Enter The Filter''s Matrix';
            app.GaussianFilter1_2 = uispinner(app.GaussianFilteringTab);
            app.GaussianFilter2_2 = uispinner(app.GaussianFilteringTab);
            app.GaussianFilter3_2 = uispinner(app.GaussianFilteringTab);
            app.GaussianFilter1_3 = uispinner(app.GaussianFilteringTab);
            app.GaussianFilter2_3 = uispinner(app.GaussianFilteringTab);
            app.GaussianFilter3_3 = uispinner(app.GaussianFilteringTab);
            app.ImageSmoothingusingGaussianFilteringLabel = uilabel(app.GaussianFilteringTab);
            app.ImageSmoothingusingGaussianFilteringLabel.Text = 'Image Smoothing using Gaussian Filtering';
            app.FrequencySmoothingTab = uitab(app.TabGroup);
            app.FrequencySmoothingTab.Title = 'Frequency Smoothing';
            app.UIAxesFreqSmoothing = uiaxes(app.FrequencySmoothingTab);
            title(app.UIAxesFreqSmoothing, 'Filtering Result')
            xlabel(app.UIAxesFreqSmoothing, '')
            ylabel(app.UIAxesFreqSmoothing, '')
            app.ChooseFilterDropDownLabel = uilabel(app.FrequencySmoothingTab);
            app.ChooseFilterDropDownLabel.Text = 'Choose Filter';
            app.ChooseFilterDropDown = uidropdown(app.FrequencySmoothingTab);
            app.ChooseFilterDropDown.Items = {'ILPF', 'GLPF', 'BLPF', 'IHPF', 'GHPF', 'BHPF'};
            app.ChooseFilterDropDown.Value = 'ILPF';
            app.FilteringwithLowPassandHighPassFiltersLabel = uilabel(app.FrequencySmoothingTab);
            app.FilteringwithLowPassandHighPassFiltersLabel.Text = 'Filtering with Low Pass and High Pass Filters';
            app.ChooseFileFreqSmoothing = uibutton(app.FrequencySmoothingTab, 'push');
            app.ChooseFileFreqSmoothing.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileFreqSmooth, true);
            app.ChooseFileFreqSmoothing.Text = 'Choose File';
            app.SelectFileLabelFreqSmoothing = uilabel(app.FrequencySmoothingTab);
            app.SelectFileLabelFreqSmoothing.Text = 'Select image file here: ';
            app.SelectImageFreqSmoothing = uieditfield(app.FrequencySmoothingTab, 'text');
            app.EnterD0valueLabel = uilabel(app.FrequencySmoothingTab);
            app.EnterD0valueLabel.Text = 'Enter D0 value';
            app.D0 = uieditfield(app.FrequencySmoothingTab, 'numeric');
            app.D0.ValueChangedFcn = createCallbackFcn(app, @D0ValueChanged, true);
            app.D0.Value = 50;
            app.SelectFileLabelFreqSmoothing_2 = uilabel(app.FrequencySmoothingTab);
            app.SelectFileLabelFreqSmoothing_2.Text = 'Select filter:';
            app.HighBoostingTab = uitab(app.TabGroup);
            app.HighBoostingTab.Title = 'High Boosting';
            app.GridLayout4 = uigridlayout(app.HighBoostingTab);
            app.SourceImageButton = uibutton(app.GridLayout4, 'push');
            app.SourceImageButton.ButtonPushedFcn = createCallbackFcn(app, @SourceImageButtonPushed, true);
            app.SourceImageButton.Text = 'Choose Image';
            app.HighBoostButton = uibutton(app.GridLayout4, 'push');
            app.HighBoostButton.ButtonPushedFcn = createCallbackFcn(app, @HighBoostButtonPushed, true);
            app.HighBoostButton.Text = 'Apply Highboost coefficient';
            app.SourceEditField = uieditfield(app.GridLayout4, 'text');
            app.SourceImage = uiimage(app.GridLayout4);
            app.HighBoostImage = uiimage(app.GridLayout4);
            app.HighBoostEditField = uieditfield(app.GridLayout4, 'numeric');
            app.HighBoostEditField.Value = 2.5;
            app.LowPassImage = uiimage(app.GridLayout4);
            app.MedianFilteringTab = uitab(app.TabGroup);
            app.MedianFilteringTab.Title = 'Median Filtering';
            app.GridLayout5 = uigridlayout(app.MedianFilteringTab);
            app.ClearImageButton = uibutton(app.GridLayout5, 'push');
            app.ClearImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImageButtonPushed, true);
            app.ClearImageButton.Text = 'Choose Image Source';
            app.ClearEditField = uieditfield(app.GridLayout5, 'text');
            app.ClearImage = uiimage(app.GridLayout5);
            app.NoiseImage = uiimage(app.GridLayout5);
            app.MedianImage = uiimage(app.GridLayout5);
            app.NoiseEditField = uieditfield(app.GridLayout5, 'text');
            app.SaltPepperEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SaltPepperEditField.Value = 0.05;
            app.NoiseImageButton = uibutton(app.GridLayout5, 'push');
            app.NoiseImageButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseImageButtonPushed, true);
            app.NoiseImageButton.Text = 'Choose Image Noise';
            app.SaltPepperButton = uibutton(app.GridLayout5, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = 'or Apply Salt Pepper from Source';
            app.ResultImageButton = uibutton(app.GridLayout5, 'push');
            app.ResultImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResultImageButtonPushed, true);
            app.ResultImageButton.Text = 'Filter the Image Noise!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end